classdef myClassifierApp2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ChangemodelsetDropDown       matlab.ui.control.DropDown
        ChangemodelsetDropDownLabel  matlab.ui.control.Label
        Button_9                     matlab.ui.control.Button
        Button_8                     matlab.ui.control.Button
        Button_7                     matlab.ui.control.Button
        Button_6                     matlab.ui.control.Button
        ModelSelectionButtonGroup    matlab.ui.container.ButtonGroup
        Button_5                     matlab.ui.control.RadioButton
        Button_4                     matlab.ui.control.RadioButton
        Button_3                     matlab.ui.control.RadioButton
        FinalButton_2                matlab.ui.control.RadioButton
        FolderLabel                  matlab.ui.control.Label
        ChangetestimageButton        matlab.ui.control.Button
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Define variables to be managed in UI/UI上で管理する変数を定義
        imds % Description
        selectedButton
        classifier
        netresult
        Text
        accuracy
        m
        data
        idx
        YPred
        score
        r
        numnet
        folder_name
    end
    
    methods (Access = private) % Define the functions to be used in this app/このアプリ内で利用する関数を定義
        % Actions to be performed when image data is read/画像データを読み出した時に実行する動作
        function readimagesaction(app)
            app.imds = imageDatastore(app.folder_name, 'IncludeSubfolders', true, ...
                'LabelSource', 'foldernames');
            if numel(app.imds.Files) > 50
                app.imds = splitEachLabel(app.imds,5); % If the number of images is 50 or more, select 50/画像の枚数が50枚以上の場合は50枚を選択
            end
            [folderpath,~,~] = fileparts(app.imds.Files{1}); % Get only Path/Pathだけを取り出し
            k = strfind(folderpath,'\'); 
            folder_disp = folderpath(k(end-2):k(end)); % Dispaly folder relative path/相対パスでのフォルダの表示
            app.FolderLabel.Text = ['folder:  ' folder_disp]; 
            app.accuracy = app.netresult.accuracy;
            app.m = app.netresult.m; % Save Iteration/Iterationを保存
            % Display trainng progress/学習曲線を表示     
            plot(app.UIAxes,[1:30],app.accuracy); 
            line(app.UIAxes,[0 app.m(4)],[app.accuracy(app.m(app.numnet)) app.accuracy(app.m(app.numnet))],'Color','red','LineStyle','--')
            mypredict(app, app.netresult); % calling a function that predicts with the whole data/全体の推論をする関数の呼び出し
            individualresult(app); % Calling a function that predicts and display with individual data/個別の推論&表示をする関数の呼び出し
        end
        
        
        % Prediction with the whole data
        function  mypredict(app, netresult)
          
            reset(app.imds); 
            [app.YPred,app.score] = classify(app.classifier,app.imds);
            for ii = 1:numel(app.imds.Labels)
                Itest = read(app.imds); % read image/画像の読み込み
                YPred1 = classify(app.classifier,Itest); 
                Itest3 = cat(3,Itest,Itest,Itest); %convert image to RGB/画像をカラーに変換
                if ~(YPred1 == app.imds.Labels(ii))
                    Color = 'red'; % show red when prediction is wrong/予測結果が間違っていたら赤
                else
                    Color = 'green'; % show green when prediction is corect/予測が正しければ緑
                end
                Itest3 = insertShape(Itest3,'Rectangle',[1 1 28 28],...
                    'Color', Color,'LineWidth',3); 
                app.data{ii} = Itest3; 
            end
            numcorrect = sum(app.YPred == app.imds.Labels);
            montage(app.data,'Size',[10 5],'Parent', app.UIAxes2);
            app.UIAxes2.Title.String = ['The number of correctly predicted: ' num2str(numcorrect) ' / 50'];
            ax2size = app.UIAxes2.Position;
            app.r = rectangle('Position',[-1,-1,90,90],'EdgeColor','y','LineWidth',3,'Parent', app.UIAxes2); 
            
        end
        % Display selected image and the score/選択画像の表示とスコア表示
        function individualresult(app)
            [img,finfo] = readimage(app.imds,app.idx); %read specified image/指定した画像を取り出す
            [~,fname,ext] = fileparts(finfo.Filename); 
            app.UIAxes3.Title.String = [fname ext]; 
            app.UIAxes3.Title.Interpreter ='none';  
            imshow(img, 'Parent',app.UIAxes3) 
            cats = categories(app.imds.Labels);
            bar(app.UIAxes3_2, categorical(cats), app.score(app.idx,:)) 
            ylim(app.UIAxes3_2, [0 1])
          
            row = ceil(app.idx / 5); 
            col = mod(app.idx,5); 
            if col == 0 
                col =5;
            end
            app.r.Position = [87*(col-1)-1,88*(row-1)-1,90,90]; %Put yellow frame on selected position/指定した場所に黄色枠の移動
        end
        % select 25%/50%/75% accuracy network
        function results = selectnetwork(app)
            app.selectedButton = app.ModelSelectionButtonGroup.SelectedObject; %Read selected radio button/選択されたラジオボタンを読み取り
            app.Text = app.selectedButton.Text; %Read the text from  selected radio button/% そのテキストを読み取り
            % Switch network depending on the conditions/条件ごとに利用するネットワークを切り替え
            if strcmp(app.Text,'Final')
                app.classifier = app.netresult.net;
                app.numnet = 4;
            elseif strcmp(app.Text,'25%') 
                app.classifier = app.netresult.net25;
                app.numnet = 1;                
            elseif strcmp(app.Text,'50%') 
                app.classifier = app.netresult.net50;
                app.numnet = 2;                
            elseif strcmp(app.Text,'75%')
                app.classifier = app.netresult.net75;
                app.numnet = 3;                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            Items = ls('netresult*'); 
            app.idx = 1; 
            app.netresult = load('netresult.mat'); % Read saved network and results in my handwrittentrain_visualization2_0/myhandwrittentrain_visualization2_0で保存したネットワークと結果を読み出し
            app.classifier = app.netresult.net; 
            app.ChangemodelsetDropDown.Items = string(Items); 
            app.folder_name = 'mypic\test'; 
            selectnetwork(app); 
            readimagesaction(app);
            
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            % Set action of rightward button
            newidx = app.idx +1;
            if newidx > 50 
                app.idx = app.idx;
            else
                app.idx = newidx;
            end
            individualresult(app);%Check score of new idx/新しいnewidxに対してスコアを確認
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            % set action of leftward button
            newidx = app.idx - 1; 
            if newidx < 1 
                app.idx = app.idx;
            else
                app.idx = newidx;
            end
            individualresult(app);%Check score of new idx/新しいnewidxに対してスコアを確認        
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            % set action of downward button
            newidx = app.idx +5;
            if newidx > 50
                app.idx = app.idx;
            else
                app.idx = newidx;
            end
            individualresult(app);   %Check score of new idx/新しいnewidxに対してスコアを確認  
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            % set action of upward button
            newidx = app.idx - 5;
            if newidx < 1
                app.idx = app.idx;
            else
                app.idx = newidx;
            end
            individualresult(app);  %Check score of new idx/新しいnewidxに対してスコアを確認        
        end

        % Selection changed function: ModelSelectionButtonGroup
        function ModelSelectionButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.ModelSelectionButtonGroup.SelectedObject; % case when model section is changed/モデル選択のボタンが変更された場合
            selectnetwork(app);
            readimagesaction(app);

        end

        % Button pushed function: ChangetestimageButton
        function ChangetestimageButtonPushed(app, event)
           uidir = uigetdir;
           if uidir ==0
           else
           app.folder_name = [uidir '\test']; % When pressing the button to change the image data, get the folder name from GUI/画像データを変更するボタンを押した場合、GUIからフォルダ名を取得
           end
           readimagesaction(app);
        end

        % Value changed function: ChangemodelsetDropDown
        function ChangemodelsetDropDownValueChanged(app, event)
            value = app.ChangemodelsetDropDown.Value; % Change model set/モデルセットを変更
            app.netresult = load(value); % Re-read network and results/ネットワークと結果の読み直し
            selectnetwork(app); % select network/ネットワークの選択
            readimagesaction(app); % 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Training Progress')
            xlabel(app.UIAxes, 'Iteration')
            ylabel(app.UIAxes, 'Accuracy')
            app.UIAxes.YLim = [0 1];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            title(app.UIAxes3_2, 'Score')
            xlabel(app.UIAxes3_2, 'Category')
            ylabel(app.UIAxes3_2, 'Probability')
            app.ChangetestimageButton = uibutton(app.UIFigure, 'push');
            app.ChangetestimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChangetestimageButtonPushed, true);
            app.ChangetestimageButton.Text = 'Change test image';
            app.FolderLabel = uilabel(app.UIFigure);
            app.FolderLabel.Text = 'Folder:';
            app.ModelSelectionButtonGroup = uibuttongroup(app.UIFigure);
            app.ModelSelectionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModelSelectionButtonGroupSelectionChanged, true);
            app.ModelSelectionButtonGroup.Title = 'Model Selection';
            app.FinalButton_2 = uiradiobutton(app.ModelSelectionButtonGroup);
            app.FinalButton_2.Text = 'Final';
            app.FinalButton_2.Value = true;
            app.Button_3 = uiradiobutton(app.ModelSelectionButtonGroup);
            app.Button_3.Text = '25%';
            app.Button_4 = uiradiobutton(app.ModelSelectionButtonGroup);
            app.Button_4.Text = '50%';
            app.Button_5 = uiradiobutton(app.ModelSelectionButtonGroup);
            app.Button_5.Text = '75%';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '→';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '←';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '↑';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '↓';
            app.ChangemodelsetDropDownLabel = uilabel(app.UIFigure);
            app.ChangemodelsetDropDownLabel.Text = 'Change model set';
            app.ChangemodelsetDropDown = uidropdown(app.UIFigure);
            app.ChangemodelsetDropDown.ValueChangedFcn = createCallbackFcn(app, @ChangemodelsetDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = myClassifierApp2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end